classdef proses < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        KembalikeBerandaButton  matlab.ui.control.Button
        AreaEditField_7         matlab.ui.control.EditField
        AreaEditFieldLabel_7    matlab.ui.control.Label
        AreaEditField_6         matlab.ui.control.EditField
        AreaEditFieldLabel_6    matlab.ui.control.Label
        AreaEditField_5         matlab.ui.control.EditField
        AreaEditFieldLabel_5    matlab.ui.control.Label
        AreaEditField_4         matlab.ui.control.EditField
        AreaEditFieldLabel_4    matlab.ui.control.Label
        AreaEditField_3         matlab.ui.control.EditField
        AreaEditFieldLabel_3    matlab.ui.control.Label
        AreaEditField_2         matlab.ui.control.EditField
        AreaEditFieldLabel_2    matlab.ui.control.Label
        AreaEditField           matlab.ui.control.EditField
        AreaEditFieldLabel      matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        ResetButton             matlab.ui.control.Button
        ProcessButton           matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        BrowseImageButton       matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        Asli                    matlab.ui.control.UIAxes
        Binner                  matlab.ui.control.UIAxes
        Blue                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function sublot(app, event)
            my_table = readtable('data_latih.xlsx','Range','A2:E121');
            
            app.UITable.Data = my_table;
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
        global Im
        [nama_file,nama_path] = uigetfile({'*.*'});
            if ~isequal(nama_file,0)
                Im = imread(fullfile(nama_path,nama_file));
                Im2 = imresize(Im,[300,300]);
                imshow(Im2, 'Parent' ,app.Asli);
            else
            end
            handle.IMG = Im;
            guidata(app.BrowseImageButton,Im);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Button down function: Asli
        function AsliButtonDown(app, event)
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global Im
            
            latih = xlsread('extract_baru.xlsx');
            Im2 = imresize(Im,[300,300]);
            Blue = Im2(:,:,3);
            [A, R] = size(Blue);
            Seg = zeros(A,R);
            
            for k = 1 : A
                for l = 1 : R
                    if Blue(k,l) > 100
                    Seg(k,l) = 0;
                    else
                    Seg(k,l) = 1;
                    end
                end
            end
            
            Seg2 = imfill(Seg);
            stats = regionprops(Seg2,'Area','Perimeter','Eccentricity','MajorAxisLength','MinorAxisLength','BoundingBox');
            
            bb = vertcat(stats(:).BoundingBox);
            x  = bb(:,1);
            y = bb(:,2);
            width = bb(:,3);
            height = bb(:,4);
            w = max(x+width)-x+1;
            h = max(y+height)-y+1;
            aspect_ratio = w/h;
            area = stats.Area;
            perimeter = stats.Perimeter;
            metric = 4*pi*area/(perimeter^2);
            eccentricity = stats.Eccentricity;
            diameter = mean([stats.MajorAxisLength stats.MinorAxisLength],2);
            
            training = [area;perimeter;metric;eccentricity;aspect_ratio]';
            hasil = sqrt((latih(:,3)-metric).^2  + (latih(:,4)-eccentricity).^2 + (latih(:,5)-aspect_ratio).^2);
            jarak = hasil(:,:);
            %data_latih = latih(:,1:5);
            pegagan = latih(:,6);
            tableA = table(jarak,pegagan);
            tableB = sortrows(tableA);
            
            hasilknn = tableB(1:5,:);


            jumlah = sum(hasilknn{:,2});
            ya = jumlah;
            if ya >= 3
                app.AreaEditField_7.Value = ('Bukan Pegagan');
            else
                app.AreaEditField_7.Value = ('Pegagan');
            end
             
            imshow(Blue, 'Parent',app.Blue);
            imshow(Seg2, 'Parent',app.Binner);

            app.AreaEditField.Value = num2str(area);
            app.AreaEditField_2.Value = num2str(perimeter);
            app.AreaEditField_3.Value = num2str(metric);
            app.AreaEditField_4.Value = num2str(diameter);
            app.AreaEditField_5.Value = num2str(eccentricity);
            app.AreaEditField_6.Value = num2str(aspect_ratio);
            
            t = readtable('extract_baru.xlsx');
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.Asli,'Parent', 'reset');
            cla(app.Blue,'Parent', 'reset');
            cla(app.Binner,'Parent', 'reset');
            app.AreaEditField.Value = '';
            app.AreaEditField_2.Value = '';
            app.AreaEditField_3.Value = '';
            app.AreaEditField_4.Value = '';
            app.AreaEditField_5.Value = '';
            app.AreaEditField_6.Value = '';
            app.AreaEditField_7.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'Sistem identifikasi pegagan2.png';
            app.Blue = uiaxes(app.UIFigure);
            title(app.Blue, 'Blue')
            xlabel(app.Blue, 'X')
            ylabel(app.Blue, 'Y')
            zlabel(app.Blue, 'Z')
            app.Binner = uiaxes(app.UIFigure);
            title(app.Binner, 'Biner')
            xlabel(app.Binner, 'X')
            ylabel(app.Binner, 'Y')
            zlabel(app.Binner, 'Z')
            app.Asli = uiaxes(app.UIFigure);
            title(app.Asli, 'Asli')
            xlabel(app.Asli, 'X')
            ylabel(app.Asli, 'Y')
            zlabel(app.Asli, 'Z')
            app.Asli.ButtonDownFcn = createCallbackFcn(app, @AsliButtonDown, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'prosesbg.png';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.Text = 'Stop';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Area'; 'Perimeter'; 'Bentuk'; 'Circularity'};
            app.UITable.RowName = {};
            app.AreaEditFieldLabel = uilabel(app.UIFigure);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.UIFigure, 'text');
            app.AreaEditField.Value = 'sss';
            app.AreaEditFieldLabel_2 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_2.Text = 'Parameter';
            app.AreaEditField_2 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_2.Value = 'sss';
            app.AreaEditFieldLabel_3 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_3.Text = 'Metrik';
            app.AreaEditField_3 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_3.Value = 'sss';
            app.AreaEditFieldLabel_4 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_4.Text = 'Diameter';
            app.AreaEditField_4 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_4.Value = 'sss';
            app.AreaEditFieldLabel_5 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_5.Text = 'Eccentricity';
            app.AreaEditField_5 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_5.Value = 'sss';
            app.AreaEditFieldLabel_6 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_6.Text = 'Aspect Ratio';
            app.AreaEditField_6 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_6.Value = 'sss';
            app.AreaEditFieldLabel_7 = uilabel(app.UIFigure);
            app.AreaEditFieldLabel_7.Text = 'Hasil';
            app.AreaEditField_7 = uieditfield(app.UIFigure, 'text');
            app.AreaEditField_7.Value = 'sss';
            app.KembalikeBerandaButton = uibutton(app.UIFigure, 'push');
            app.KembalikeBerandaButton.Text = 'Kembali ke Beranda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proses

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end